classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        BAtualizar         matlab.ui.control.Button
        BGMetodos          matlab.ui.container.ButtonGroup
        rbTodos            matlab.ui.control.RadioButton
        rbOutro            matlab.ui.control.RadioButton
        rbODE45            matlab.ui.control.RadioButton
        rbRK4              matlab.ui.control.RadioButton
        rbRK2              matlab.ui.control.RadioButton
        rbEulerM           matlab.ui.control.RadioButton
        rbEuler            matlab.ui.control.RadioButton
        DadosPVI           matlab.ui.container.Panel
        Ey0                matlab.ui.control.NumericEditField
        y0EditFieldLabel   matlab.ui.control.Label
        En                 matlab.ui.control.NumericEditField
        nEditFieldLabel    matlab.ui.control.Label
        Eb                 matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        Ea                 matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        Efuncao            matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
        Menu               matlab.ui.container.Menu
        mSair              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            BAtualizarButtonPushed(app, [])
        end

        % Button pushed function: BAtualizar
        function BAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.Efuncao.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.Ea.Value;
            b = app.Eb.Value;
            n = app.En.Value;
            y0 = app.Ey0.Value;

            escolhabgMetodos = app.BGMetodos.SelectedObject;
            metodoSelecionado = find([app.rbEuler,...
                                    app.rbEulerM,...
                                    app.rbRK2,...
                                    app.rbRK4,...
                                    app.rbODE45,...
                                    app.rbOutro,...
                                    app.rbTodos]==escolhabgMetodos);

            erroFuncao = MException("MATLAB:BAtualizar:badF",...
                "Introduza uma função em t e y");
            erroFuncao1 = MException("MATLAB:BAtualizar:badA",...
                "Introduza b > a");
                
            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.Ea.BackgroundColor = [1,0,0]; %[r,g,b]
                app.Ea.FontColor = "w";
                app.Eb.BackgroundColor = [1,0,0]; %[r,g,b]
                app.Eb.FontColor = "w";
                throw(erroFuncao1)
            else
                app.Ea.BackgroundColor = "w";
                app.Ea.FontColor = [0,0,0];
                app.Eb.BackgroundColor = "w";
                app.Eb.FontColor = [0,0,0];
            end

            try
                try
                    z = f(t,y);
                    app.Efuncao.BackgroundColor = "w";
                    app.Efuncao.FontColor = [0,0,0];

                catch
                    app.Efuncao.BackgroundColor = [1,0,0]; %[r,g,b]
                    app.Efuncao.FontColor = "w";
                    throw(erroFuncao)                    

                end

                yEuler = NEuler(f,a,b,n,y0);
                yRK2 = NRK2(f,a,b,n,y0);
                yRK4 = NRK4(f,a,b,n,y0);
                yEulerM = NEulerMelhorado(f,a,b,n,y0);
                [~,yODE45] = NODE45(f,a,b,n,y0);
                yRK3 = NRK3(f,a,b,n,y0);
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                
                g =@(t) eval(vectorize(char(sExata)));
                
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroEulerM = abs(yExata-yEulerM);
                erroODE45 = abs(yExata-yODE45.');
                erroRK3 = abs(yExata-yRK3);

                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")

                switch metodoSelecionado
                    case 1
                        plot(app.UIAxes,t,yEuler,"-g*")
                        legenda = {'Exata','Euler'};
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunas = {'t','Exata','Euler','erroEuler'};
                    case 2
                        plot(app.UIAxes,t,yEulerM,"-black>")
                        legenda = {'Exata','EulerM'};
                        tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                        nomeColunas = {'t','Exata','EulerM','erroEulerM'};
                    case 3
                        plot(app.UIAxes,t,yRK2,"-bh")
                        legenda = {'Exata','RK2'};
                        tabela = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas = {'t','Exata','RK2','erroRK2'};
                    case 4
                        plot(app.UIAxes,t,yRK4,"-md")
                        legenda = {'Exata','RK4'};
                        tabela = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunas = {'t','Exata','RK4','erroRK4'};
                    case 5
                        plot(app.UIAxes,t,yODE45,"-cs")
                        legenda = {'Exata','ODE45'};
                        tabela = [t.',yExata.',yODE45,erroODE45.'];
                        nomeColunas = {'t','Exata','ODE45','erroODE45'};
                    case 6
                        plot(app.UIAxes,t,yRK3,"-yx")
                        legenda = {'Exata','RK3'};
                        tabela = [t.',yExata.',yRK3.',erroRK3.'];
                        nomeColunas = {'t','Exata','RK3','erroRK3'};                     
                    case 7
                        plot(app.UIAxes,t,yEuler,"-g*")
                        plot(app.UIAxes,t,yEulerM,"-black>")
                        plot(app.UIAxes,t,yRK2,"-b+")
                        plot(app.UIAxes,t,yRK4,"-m+")
                        plot(app.UIAxes,t,yODE45,"-c+")
                        plot(app.UIAxes,t,yRK3,"-yx")
                        legenda = {'Exata','Euler','EulerM','RK2','RK4','ODE45','RK3'};
                        tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK4.',yODE45,yRK3.'...
                                    erroEuler.',erroEulerM.',erroRK2.',erroRK4.',erroODE45.',erroRK3.'];
                        nomeColunas = {'t','Exata','Euler','EulerM','RK2','RK4','ODE45','RK3'...
                            'erroEuler','erroEulerM','erroRK2','erroRK4','erroODE45','erroRK3'};

                end

                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;

                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legenda)

            catch ME
                errordlg(ME.message, "Erro","modal")

            end
            
        end

        % Menu selected function: mSair
        function mSairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Value changed function: Eb
        function EbValueChanged(app, event)
            value = app.Eb.Value;
            if(value<app.Ea.Value)
                errordlg("Introduza b > a", "Erro","modal")
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {601, 601};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {306, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosPVI);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.Efuncao = uieditfield(app.DadosPVI, 'text');
            app.Efuncao.Value = '-2*t*y';
            app.aEditFieldLabel = uilabel(app.DadosPVI);
            app.aEditFieldLabel.Text = {'a'; ''};
            app.Ea = uieditfield(app.DadosPVI, 'numeric');
            app.bEditFieldLabel = uilabel(app.DadosPVI);
            app.bEditFieldLabel.Text = {'b'; ''};
            app.Eb = uieditfield(app.DadosPVI, 'numeric');
            app.Eb.ValueChangedFcn = createCallbackFcn(app, @EbValueChanged, true);
            app.Eb.Value = 1.5;
            app.nEditFieldLabel = uilabel(app.DadosPVI);
            app.nEditFieldLabel.Text = {'n'; ''; ''};
            app.En = uieditfield(app.DadosPVI, 'numeric');
            app.En.Limits = [2 Inf];
            app.En.Value = 10;
            app.y0EditFieldLabel = uilabel(app.DadosPVI);
            app.y0EditFieldLabel.Text = {'y0'; ''; ''};
            app.Ey0 = uieditfield(app.DadosPVI, 'numeric');
            app.Ey0.Value = 2;
            app.BGMetodos = uibuttongroup(app.LeftPanel);
            app.BGMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.BGMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.BGMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.BGMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.BGMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.BGMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbOutro = uiradiobutton(app.BGMetodos);
            app.rbOutro.Text = 'RK3';
            app.rbTodos = uiradiobutton(app.BGMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.BAtualizar = uibutton(app.LeftPanel, 'push');
            app.BAtualizar.ButtonPushedFcn = createCallbackFcn(app, @BAtualizarButtonPushed, true);
            app.BAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.mSair = uimenu(app.Menu);
            app.mSair.MenuSelectedFcn = createCallbackFcn(app, @mSairMenuSelected, true);
            app.mSair.Text = 'Sair';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end