classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ProblemasPrdefinidosDropDown  matlab.ui.control.DropDown
        ProblemasPrdefinidosDropDownLabel  matlab.ui.control.Label
        bAtualizar                    matlab.ui.control.Button
        bgMetodos                     matlab.ui.container.ButtonGroup
        rbTodos                       matlab.ui.control.RadioButton
        rbRK4                         matlab.ui.control.RadioButton
        rbRK2                         matlab.ui.control.RadioButton
        rbEulerM                      matlab.ui.control.RadioButton
        rbEuler                       matlab.ui.control.RadioButton
        DadosPVI                      matlab.ui.container.Panel
        eF                            matlab.ui.control.EditField
        gtuvLabel_2                   matlab.ui.control.Label
        eV0                           matlab.ui.control.Label
        valorV0                       matlab.ui.control.NumericEditField
        eG                            matlab.ui.control.EditField
        gtuvLabel                     matlab.ui.control.Label
        valorU0                       matlab.ui.control.NumericEditField
        eU0                           matlab.ui.control.Label
        eN                            matlab.ui.control.Label
        valorN                        matlab.ui.control.NumericEditField
        eB                            matlab.ui.control.Label
        valorB                        matlab.ui.control.NumericEditField
        valorA                        matlab.ui.control.NumericEditField
        aLabel                        matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        Menu                          matlab.ui.container.Menu
        mSair                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms u(t) v(t)
            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));
            a = app.valorA.Value;
            b = app.valorB.Value;   
            n = app.valorN.Value;           
            v0 = app.valorV0.Value;      
            u0 = app.valorU0.Value; 
            drop = app.ProblemasPrdefinidosDropDown.Value;


            escolhabgMetodos = app.bgMetodos.SelectedObject;
            metodoSelecionado = find([app.rbEuler,...
                                     app.rbEulerM,...
                                     app.rbRK2,...
                                     app.rbRK4,...
                                     app.rbTodos]==escolhabgMetodos);
            
            erroFuncao = MException("MATLAB:bAtualizar:badF",...
                                    "Introduza uma função em t, u e v");
             %outras
            
            
            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.valorA.BackgroundColor = [1,0,0]; %[r,g,b]
                app.valorA.FontColor = "w";
                app.valorB.BackgroundColor = [1,0,0]; %[r,g,b]
                app.valorB.FontColor = "w";
                throw(erroFuncao1)
            else
                app.valorA.BackgroundColor = "w";
                app.valorA.FontColor = [0,0,0];
                app.valorB.BackgroundColor = "w";
                app.valorB.FontColor = [0,0,0];
            end


            try
                try
                  %  z = f(t,u,v);
                    app.eF.BackgroundColor = "w";
                    app.eF.FontColor = [0,0,0];
                catch 
                    app.eF.BackgroundColor = [1,0,0]; %[r,g,b] 
                    app.eF.FontColor = "w";
                    throw(erroFuncao)
                end

                eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                if(drop == "Vibração Forçada  não Amotecida (Batimento)")
                    eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                end
                if(drop == "Sistema Mola-Massa C/ Amortecimento")
                    eqns = [diff(u,t)==v,diff(v,t)==-10*v-25*u];
                end
                if(drop == "Ressonância Mecânica")
                    eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                end
                
                ci = [u(0)==u0,v(0)==v0];
                [sExataU,sExataV] = dsolve(eqns,ci);

                   
                p =@(t) eval(vectorize(char(sExataU)));
                
                
                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [t,uEulerM,~] = NEulerMelhoradoSED(f,g,a,b,n,u0,v0);
                [t,uRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
                [t,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                
                
                uExata = p(t);

                h = (b-a)/n;
                t = a:h:b;

                
                erroEuler = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);

                %plot(app.UIAxes,t,uExata,"-blacko")
                %hold(app.UIAxes,"on")  
                
                
                switch metodoSelecionado
                    case 1
                        if(drop == "Pêndulo")
                            plot(app.UIAxes,t,uEuler,"-ro")  
                            legenda = {'Euler'};
                            tabela = [t.',uEuler.'];
                            nomeColunas = {'t','Euler'};
                        else 
                            plot(app.UIAxes,t,uExata,"-blacko")
                            hold(app.UIAxes,"on")  
                            plot(app.UIAxes,t,uEuler,"-ro")  
                            legenda = {'Exata','Euler'};
                            tabela = [t.',uExata.',uEuler.',erroEuler.'];
                            nomeColunas = {'t','Exata','Euler','Erro Euler'};
                        end
                    case 2
                        if(drop == "Pêndulo")
                            plot(app.UIAxes,t,uEulerM,"-g*")  
                            legenda = {'Euler Melhorado'};
                            tabela = [t.',uEulerM.'];
                            nomeColunas = {'t','EulerM'};
                        else
                            plot(app.UIAxes,t,uExata,"-blacko")
                            hold(app.UIAxes,"on") 
                            plot(app.UIAxes,t,uEulerM,"-g*")  
                            legenda = {'Exata','Euler Melhorado'};
                            tabela = [t.',uExata.',uEulerM.',erroEulerM.'];
                            nomeColunas = {'t','Exata','EulerM','Erro EulerM'};
                        end
                    case 3
                        if(drop == "Pêndulo")
                            plot(app.UIAxes,t,uRK2,"-b+")  
                            legenda = {'RK2'};
                            tabela = [t.',uRK2.'];
                            nomeColunas = {'t','RK2'};  
                        else
                            plot(app.UIAxes,t,uExata,"-blacko")
                            hold(app.UIAxes,"on") 
                            plot(app.UIAxes,t,uRK2,"-b+")  
                            legenda = {'Exata','RK2'};
                            tabela = [t.',uExata.',uRK2.',erroRK2.'];
                            nomeColunas = {'t','Exata','RK2','Erro RK2'}; 
                        end
                    case 4
                        if(drop == "Pêndulo")
                            plot(app.UIAxes,t,uRK4,"-y>")  
                            legenda = {'RK4'};
                            tabela = [t.',uRK4.'];
                            nomeColunas = {'t','RK4'};
                        else
                            plot(app.UIAxes,t,uExata,"-blacko")
                            hold(app.UIAxes,"on") 
                            plot(app.UIAxes,t,uRK4,"-y>")  
                            legenda = {'Exata','RK4'};
                            tabela = [t.',uExata.',uRK4.',erroRK4.'];
                            nomeColunas = {'t','Exata','RK4','Erro RK4'};
                        end
                   case 5
                       if(drop == "Pêndulo")
                            plot(app.UIAxes,t,uRK4,"-y>") 
                            hold(app.UIAxes,"on") 
                            plot(app.UIAxes,t,uRK2,"-b+") 
                            plot(app.UIAxes,t,uEulerM,"-g*")
                            plot(app.UIAxes,t,uEuler,"-ro")
                            legenda = {'RK4','RK2','Euler Melhorado','Euler'};
                            tabela = [t.',uRK4.',uRK2.',uEulerM.',uEuler.'];
                            nomeColunas = {'t','RK4','RK2','Euler Melhorado','Euler'}; 
                       else
                            plot(app.UIAxes,t,uExata,"-blacko")
                            hold(app.UIAxes,"on") 
                            plot(app.UIAxes,t,uRK4,"-y>") 
                            plot(app.UIAxes,t,uRK2,"-b+") 
                            plot(app.UIAxes,t,uEulerM,"-g*")
                            plot(app.UIAxes,t,uEuler,"-ro")
                            legenda = {'Exata','RK4','RK2','Euler Melhorado','Euler'};
                            tabela = [t.',uExata.',uRK4.',uRK2.',uEulerM.',uEuler.',erroRK4.',erroRK2.',erroEulerM.',erroEuler.'];
                            nomeColunas = {'t','Exata','RK4','RK2','Euler Melhorado','Euler','Erro RK4','Erro RK2','Erro EulerM','Erro Euler'}; 
                       end
                end               
 
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;

                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legenda)
                
            catch ME
                errordlg(ME.message,"Erro","modal")
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: mSair
        function mSairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Value changed function: eG
        function eGValueChanged(app, event)
         
        end

        % Value changed function: valorB
        function valorBValueChanged(app, event)
            value = app.valorB.Value;
            if(value<app.valorA.Value)
                errordlg("Introduza b > a", "Erro","modal")
            end
            
        end

        % Value changed function: ProblemasPrdefinidosDropDown
        function ProblemasPrdefinidosDropDownValueChanged(app, event)
            syms u(t) v(t)
            drop = app.ProblemasPrdefinidosDropDown.Value;

            switch drop
                    case "Pêndulo"
                        %msgbox("pendulo");
                        app.eG.Value = "-0.3*v-sin(u)";
                        app.valorA.Value = 0;
                        app.valorB.Value = 200;
                        app.valorN.Value = 3000;
                        app.valorU0.Value = pi/2;
                        app.valorV0.Value = 0;
                    case "Vibração Forçada  não Amotecida (Batimento)"
                        %msgbox("vibracao");  
                        app.eG.Value = "-16*u";
                        app.valorA.Value = 0;
                        app.valorB.Value = 10;
                        app.valorN.Value = 300;
                        app.valorU0.Value = 25;
                        app.valorV0.Value = -5;
                    case "Sistema Mola-Massa S/ Amortecimento"
                        %msgbox("mola-s");
                        app.eG.Value = "-16*u";
                        app.valorA.Value = 0;
                        app.valorB.Value = 4;
                        app.valorN.Value = 100;
                        app.valorU0.Value = 9;
                        app.valorV0.Value = 0;
                    case "Sistema Mola-Massa C/ Amortecimento"
                        %msgbox("mola-c");
                        app.eG.Value = "-10*v-25*u";
                        app.valorA.Value = 0;
                        app.valorB.Value = 2;
                        app.valorN.Value = 100;
                        app.valorU0.Value = 0;
                        app.valorV0.Value = -4;
                    case "Ressonância Mecânica"
                        %msgbox("ressonancia") ;
                        app.eG.Value = "-16*u";
                        app.valorA.Value = 0;
                        app.valorB.Value = 20;
                        app.valorN.Value = 1000;
                        app.valorU0.Value = -4;
                        app.valorV0.Value = 0;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.valorA = uieditfield(app.DadosPVI, 'numeric');
            app.valorB = uieditfield(app.DadosPVI, 'numeric');
            app.valorB.ValueChangedFcn = createCallbackFcn(app, @valorBValueChanged, true);
            app.valorB.Value = 200;
            app.eB = uilabel(app.DadosPVI);
            app.eB.Text = 'b';
            app.valorN = uieditfield(app.DadosPVI, 'numeric');
            app.valorN.Value = 3000;
            app.eN = uilabel(app.DadosPVI);
            app.eN.Text = 'n';
            app.eU0 = uilabel(app.DadosPVI);
            app.eU0.Text = 'u0';
            app.valorU0 = uieditfield(app.DadosPVI, 'numeric');
            app.valorU0.Value = 1.5707963267949;
            app.gtuvLabel = uilabel(app.DadosPVI);
            app.gtuvLabel.Text = 'g(t,u,v) =';
            app.eG = uieditfield(app.DadosPVI, 'text');
            app.eG.ValueChangedFcn = createCallbackFcn(app, @eGValueChanged, true);
            app.eG.Value = '-0.3*v-sin(u)';
            app.valorV0 = uieditfield(app.DadosPVI, 'numeric');
            app.eV0 = uilabel(app.DadosPVI);
            app.eV0.Text = 'v0';
            app.gtuvLabel_2 = uilabel(app.DadosPVI);
            app.gtuvLabel_2.Text = 'f(t,u,v) =';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = 'v';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.ProblemasPrdefinidosDropDownLabel = uilabel(app.LeftPanel);
            app.ProblemasPrdefinidosDropDownLabel.Text = 'Problemas Pré-definidos';
            app.ProblemasPrdefinidosDropDown = uidropdown(app.LeftPanel);
            app.ProblemasPrdefinidosDropDown.Items = {'Pêndulo', 'Vibração Forçada  não Amotecida (Batimento)', 'Sistema Mola-Massa S/ Amortecimento', 'Sistema Mola-Massa C/ Amortecimento', 'Ressonância Mecânica'};
            app.ProblemasPrdefinidosDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemasPrdefinidosDropDownValueChanged, true);
            app.ProblemasPrdefinidosDropDown.Value = 'Pêndulo';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.mSair = uimenu(app.Menu);
            app.mSair.MenuSelectedFcn = createCallbackFcn(app, @mSairMenuSelected, true);
            app.mSair.Text = 'Sair';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end