classdef tp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        IncioMenu                      matlab.ui.container.Menu
        SairMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        tgFrVr                         matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        EscolhaaderivadaDropDown       matlab.ui.control.DropDown
        EscolhaaderivadaDropDownLabel  matlab.ui.control.Label
        eH                             matlab.ui.control.NumericEditField
        PassoEditFieldLabel            matlab.ui.control.Label
        eB                             matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        eA                             matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        eF                             matlab.ui.control.EditField
        ZfxyEditFieldLabel             matlab.ui.control.Label
        harmonica                      matlab.ui.control.Label
        AtualizarButton                matlab.ui.control.Button
        DadosLabel                     matlab.ui.control.Label
        intdoxLabel                    matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        tgFrVr_2                       matlab.ui.container.Tab
        EscolhaaderivadaDropDown_2     matlab.ui.control.DropDown
        EscolhaaderivadaDropDown_2Label  matlab.ui.control.Label
        eH_2                           matlab.ui.control.NumericEditField
        PassoEditField_2Label          matlab.ui.control.Label
        eH_3                           matlab.ui.control.NumericEditField
        passoEditField_2Label          matlab.ui.control.Label
        eB_2                           matlab.ui.control.NumericEditField
        bEditField_2Label              matlab.ui.control.Label
        eD_2                           matlab.ui.control.NumericEditField
        dEditField_2Label              matlab.ui.control.Label
        eC_2                           matlab.ui.control.NumericEditField
        cEditField_2Label              matlab.ui.control.Label
        eA_2                           matlab.ui.control.NumericEditField
        aEditField_2Label              matlab.ui.control.Label
        eF_2                           matlab.ui.control.EditField
        ZfxyEditField_2Label           matlab.ui.control.Label
        harmonica_2                    matlab.ui.control.Label
        AtualizarButton_2              matlab.ui.control.Button
        DadosLabel_2                   matlab.ui.control.Label
        intdoYLabel_2                  matlab.ui.control.Label
        intdoxLabel_2                  matlab.ui.control.Label
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

      
    methods (Access = public)
                    
             function YN = isHarmonica(~,f)
                syms z(x,y)
                if (diff(f,x,2)+diff(f,y,2)==0)
                    YN = 1;
                else
                    YN = 0;
                end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ns)
            
        end

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
                        
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; h = app.eH.Value; b = app.eB.Value; 


            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.eA.BackgroundColor = [1,0,0]; %[r,g,b]
                app.eA.FontColor = "w";
                app.eB.BackgroundColor = [1,0,0]; %[r,g,b]
                app.eB.FontColor = "w";
                throw(erroFuncao)
            else
                app.eA.BackgroundColor = "w";
                app.eA.FontColor = [0,0,0];
                app.eB.BackgroundColor = "w";
                app.eB.FontColor = [0,0,0];
            end


            escolhabgMetodos = app.EscolhaaderivadaDropDown.Value;

            if(escolhabgMetodos == "Progressiva 2 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxP2] = NDerivacaoP2(f,a,b,h);
                dydxExata = g(x);
                erroP2 = abs(dydxExata -dydxP2);
                tabela = array2table([x.',y.',dydxExata.', dydxP2.',erroP2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxP2,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
            end
             if(escolhabgMetodos == "Regressiva 2 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxR2] = NDerivacaoR2(f,a,b,h);
                dydxExata = g(x);
                erroR2 = abs(dydxExata -dydxR2);
                tabela = array2table([x.',y.',dydxExata.', dydxR2.',erroR2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxR2,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)   
             end
              if(escolhabgMetodos == "Progressiva 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxP3] = NDerivacaoP3(f,a,b,h);
                dydxExata = g(x);
                erroP3 = abs(dydxExata -dydxP3);
                tabela = array2table([x.',y.',dydxExata.', dydxP3.',erroP3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxP3,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)   
              end
            if(escolhabgMetodos == "Regressiva 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxR3] = NDerivacaoR3(f,a,b,h);
                dydxExata = g(x);
                erroR3 = abs(dydxExata -dydxR3);
                tabela = array2table([x.',y.',dydxExata.', dydxR3.',erroR3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxR3,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)   
             end
            if(escolhabgMetodos == "Centrada 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxC3] = NDerivacaoC3(f,a,b,h);
                dydxExata = g(x);
                erroC3 = abs(dydxExata -dydxC3);
                tabela = array2table([x.',y.',dydxExata.', dydxC3.',erroC3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxC3,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)   
            end
            if(escolhabgMetodos == "2ª derivada 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxD2] = NDerivacaoD2(f,a,b,h);
                dydxExata = g(x);
                erroD2 = abs(dydxExata -dydxD2);
                tabela = array2table([x.',y.',dydxExata.', dydxD2.',erroD2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxD2,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
            end


            try


            catch ME
                errordlg(ME.message,"ERRO","modal")
            end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: IncioMenu
        function IncioMenuSelected(app, event)
            Menu_Inicio
            delete(app.UIFigure)
        end

        % Button pushed function: AtualizarButton_2
        function AtualizarButton_2Pushed(app, event)
                    
              strF=app.eF_2.Value;
              f=@(x,y) eval(vectorize(strF));
              %eixo dos x
              a=app.eA_2.Value;
              b=app.eB_2.Value;
              p2=app.eH_2.Value;
              %eixo dos y
              c=app.eC_2.Value;
              d=app.eD_2.Value;   
              %passo
              p=app.eH_2.Value;


            [x,y] = meshgrid(a:p2:b,c:p:d);
            Z= f(x,y);
            surf(app.UIAxes_2,x,y,Z)
            contour(app.UIAxes2_2,x,y,Z,20)
    
    
            syms x y
             if isHarmonica(app,f)
                 app.harmonica_2.Text = "A função é harmónica";
                 app.harmonica_2.BackgroundColor = "g";
             else
                 app.harmonica_2.Text = "A função não é harmónica";
                 app.harmonica_2.BackgroundColor = "r";  
             end
    
    
            dzdx = diff(f,x);
            dzdx_latex = ['$' '\frac{dz}{dx} = ' latex(dzdx) '$'];
            cla(app.UIAxes3_2)
            text(app.UIAxes3_2,0.1,0.5,dzdx_latex,'units','normalized','Interpreter','latex','FontSize',20)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '  Menu  ';
            app.IncioMenu = uimenu(app.Menu);
            app.IncioMenu.MenuSelectedFcn = createCallbackFcn(app, @IncioMenuSelected, true);
            app.IncioMenu.Text = 'Início';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = '  Função real de variável real y = f(x)  ';
            app.UIAxes = uiaxes(app.tgFrVr);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.tgFrVr);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.intdoxLabel = uilabel(app.tgFrVr);
            app.intdoxLabel.Text = 'int.do x:';
            app.DadosLabel = uilabel(app.tgFrVr);
            app.DadosLabel.Text = 'Dados';
            app.AtualizarButton = uibutton(app.tgFrVr, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = {'Atualizar'; ''};
            app.harmonica = uilabel(app.tgFrVr);
            app.harmonica.Text = 'f é harmónica';
            app.ZfxyEditFieldLabel = uilabel(app.tgFrVr);
            app.ZfxyEditFieldLabel.Text = 'Z=f(x,y)=';
            app.eF = uieditfield(app.tgFrVr, 'text');
            app.eF.Value = 'sin(x)';
            app.aEditFieldLabel = uilabel(app.tgFrVr);
            app.aEditFieldLabel.Text = 'a=';
            app.eA = uieditfield(app.tgFrVr, 'numeric');
            app.bEditFieldLabel = uilabel(app.tgFrVr);
            app.bEditFieldLabel.Text = 'b=';
            app.eB = uieditfield(app.tgFrVr, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.PassoEditFieldLabel = uilabel(app.tgFrVr);
            app.PassoEditFieldLabel.Text = 'Passo';
            app.eH = uieditfield(app.tgFrVr, 'numeric');
            app.eH.Limits = [0 Inf];
            app.eH.Value = 0.1;
            app.EscolhaaderivadaDropDownLabel = uilabel(app.tgFrVr);
            app.EscolhaaderivadaDropDownLabel.Text = 'Escolha a derivada';
            app.EscolhaaderivadaDropDown = uidropdown(app.tgFrVr);
            app.EscolhaaderivadaDropDown.Items = {'Progressiva 2 pontos', 'Regressiva 2 pontos', 'Progressiva 3 pontos', 'Regressiva 3 pontos', 'Centrada 3 pontos', '2ª derivada 3 pontos'};
            app.EscolhaaderivadaDropDown.Value = 'Progressiva 2 pontos';
            app.UITable = uitable(app.tgFrVr);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.tgFrVr_2 = uitab(app.TabGroup);
            app.tgFrVr_2.Title = '  Função real de 2 variáveis reais z = f(x,y)  ';
            app.UIAxes_2 = uiaxes(app.tgFrVr_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.tgFrVr_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.tgFrVr_2);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_2.ZTick = [];
            app.intdoxLabel_2 = uilabel(app.tgFrVr_2);
            app.intdoxLabel_2.Text = 'int.do x:';
            app.intdoYLabel_2 = uilabel(app.tgFrVr_2);
            app.intdoYLabel_2.Text = 'int.do Y';
            app.DadosLabel_2 = uilabel(app.tgFrVr_2);
            app.DadosLabel_2.Text = 'Dados';
            app.AtualizarButton_2 = uibutton(app.tgFrVr_2, 'push');
            app.AtualizarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButton_2Pushed, true);
            app.AtualizarButton_2.Text = {'Atualizar'; ''};
            app.harmonica_2 = uilabel(app.tgFrVr_2);
            app.harmonica_2.Text = 'f é harmónica';
            app.ZfxyEditField_2Label = uilabel(app.tgFrVr_2);
            app.ZfxyEditField_2Label.Text = 'Z=f(x,y)=';
            app.eF_2 = uieditfield(app.tgFrVr_2, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.aEditField_2Label = uilabel(app.tgFrVr_2);
            app.aEditField_2Label.Text = 'a=';
            app.eA_2 = uieditfield(app.tgFrVr_2, 'numeric');
            app.eA_2.Value = -4;
            app.cEditField_2Label = uilabel(app.tgFrVr_2);
            app.cEditField_2Label.Text = 'c=';
            app.eC_2 = uieditfield(app.tgFrVr_2, 'numeric');
            app.eC_2.Value = -4;
            app.dEditField_2Label = uilabel(app.tgFrVr_2);
            app.dEditField_2Label.Text = 'd=';
            app.eD_2 = uieditfield(app.tgFrVr_2, 'numeric');
            app.eD_2.Value = 4;
            app.bEditField_2Label = uilabel(app.tgFrVr_2);
            app.bEditField_2Label.Text = 'b=';
            app.eB_2 = uieditfield(app.tgFrVr_2, 'numeric');
            app.eB_2.Value = 4;
            app.passoEditField_2Label = uilabel(app.tgFrVr_2);
            app.passoEditField_2Label.Text = 'passo';
            app.eH_3 = uieditfield(app.tgFrVr_2, 'numeric');
            app.eH_3.Limits = [0 Inf];
            app.eH_3.Value = 0.1;
            app.PassoEditField_2Label = uilabel(app.tgFrVr_2);
            app.PassoEditField_2Label.Text = 'Passo';
            app.eH_2 = uieditfield(app.tgFrVr_2, 'numeric');
            app.eH_2.Limits = [0 Inf];
            app.eH_2.Value = 0.1;
            app.EscolhaaderivadaDropDown_2Label = uilabel(app.tgFrVr_2);
            app.EscolhaaderivadaDropDown_2Label.Text = 'Escolha a derivada';
            app.EscolhaaderivadaDropDown_2 = uidropdown(app.tgFrVr_2);
            app.EscolhaaderivadaDropDown_2.Items = {'Option 1', 'Option 2', 'Option 3'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tp3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end